classdef AOptimisationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SeeSawTrebuchetOptimiserPanel   matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        CounterweightMass               matlab.ui.control.EditField
        CounterweightkgLabel            matlab.ui.control.Label
        HeightofBeam                    matlab.ui.control.EditField
        HeightofBeammEditFieldLabel     matlab.ui.control.Label
        LengthofL2                      matlab.ui.control.EditField
        LengthofL2mEditFieldLabel       matlab.ui.control.Label
        LengthofL1                      matlab.ui.control.EditField
        LengthofL1mEditFieldLabel       matlab.ui.control.Label
        BeamWidth                       matlab.ui.control.NumericEditField
        BeamWidthmEditFieldLabel        matlab.ui.control.Label
        ProjectileMass                  matlab.ui.control.NumericEditField
        ProjectileMasskgEditFieldLabel  matlab.ui.control.Label
        TargetDistance                  matlab.ui.control.NumericEditField
        TargetDistancemEditFieldLabel   matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        ClearButton                     matlab.ui.control.Button
        OptimiseButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OptimiseButton
        function OptimiseButtonPushed(app, event)
            % Create fixed Variables
            s = app.TargetDistance.Value;
            m = app.ProjectileMass.Value;
            w = app.BeamWidth.Value;
            
            % Create optimization variables
            l1 = optimvar("l1","LowerBound",1,"UpperBound",15);
            l2 = optimvar("l2","LowerBound",1,"UpperBound",15);
            h = optimvar("h","LowerBound",0,"UpperBound",1);
            mcw = optimvar("mcw","LowerBound",0,"UpperBound",4000);
            
            % Set initial starting point for the solver
            initialPoint.l1 = repmat(12,size(l1));
            initialPoint.l2 = repmat(2,size(l2));
            initialPoint.h = repmat(0.1,size(h));
            initialPoint.mcw = repmat(1400,size(mcw));
            
            % Create problem
            problem = optimproblem;
            
            % Define problem objective
            [problem.Objective, MaxStress, EnergyTotal] = fcn2optimexpr(@AOptimise,l1,l2,h,mcw,s,m,w);
            
            % Define problem constraints
            problem.Constraints.Constraint1 = MaxStress <= 85000000;
            problem.Constraints.Constraint2 = l1 + l2 <= 15;
            problem.Constraints.Constraint3 = l1 >= l2;
            problem.Constraints.Constraint4 = EnergyTotal >= 0;
            problem.Constraints.Constraint5 = mcw >= 15;
            
            options = optimoptions('fmincon', 'MaxFunctionEvaluations', 3.0e+12);
            
            % Solve problem
            [solution,objectiveValue,reasonSolverStopped] = solve(problem,initialPoint,'Options',options);
            
            
            % Display results
            solution;
            reasonSolverStopped;
            objectiveValue;
            
            % Clear variables
            clearvars l1 l2 h mcw initialPoint reasonSolverStopped objectiveValue

            app.CounterweightMass.Value = num2str(solution.mcw);
            app.HeightofBeam.Value = num2str(solution.h);
            app.LengthofL1.Value = num2str(solution.l1); 
            app.LengthofL2.Value = num2str(solution.l2); 

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.CounterweightMass.Value = "";
            app.HeightofBeam.Value = "";
            app.LengthofL1.Value = ""; 
            app.LengthofL2.Value = ""; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SeeSawTrebuchetOptimiserPanel = uipanel(app.UIFigure);
            app.SeeSawTrebuchetOptimiserPanel.Title = 'See-Saw Trebuchet Optimiser';
            app.GridLayout6 = uigridlayout(app.SeeSawTrebuchetOptimiserPanel);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.OptimiseButton = uibutton(app.GridLayout7, 'push');
            app.OptimiseButton.ButtonPushedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.OptimiseButton.Text = 'Optimise';
            app.ClearButton = uibutton(app.GridLayout7, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TargetDistancemEditFieldLabel = uilabel(app.GridLayout6);
            app.TargetDistancemEditFieldLabel.Text = 'Target Distance (m) ';
            app.TargetDistance = uieditfield(app.GridLayout6, 'numeric');
            app.TargetDistance.Value = 63;
            app.ProjectileMasskgEditFieldLabel = uilabel(app.GridLayout6);
            app.ProjectileMasskgEditFieldLabel.Text = 'Projectile Mass (kg) ';
            app.ProjectileMass = uieditfield(app.GridLayout6, 'numeric');
            app.ProjectileMass.Value = 15;
            app.BeamWidthmEditFieldLabel = uilabel(app.GridLayout6);
            app.BeamWidthmEditFieldLabel.Text = 'Beam Width (m) ';
            app.BeamWidth = uieditfield(app.GridLayout6, 'numeric');
            app.BeamWidth.Value = 0.06;
            app.LengthofL1mEditFieldLabel = uilabel(app.GridLayout6);
            app.LengthofL1mEditFieldLabel.Text = 'Length of L1 (m) ';
            app.LengthofL1 = uieditfield(app.GridLayout6, 'text');
            app.LengthofL1.Editable = 'off';
            app.LengthofL2mEditFieldLabel = uilabel(app.GridLayout6);
            app.LengthofL2mEditFieldLabel.Text = 'Length of L2 (m) ';
            app.LengthofL2 = uieditfield(app.GridLayout6, 'text');
            app.LengthofL2.Editable = 'off';
            app.HeightofBeammEditFieldLabel = uilabel(app.GridLayout6);
            app.HeightofBeammEditFieldLabel.Text = 'Height of Beam (m) ';
            app.HeightofBeam = uieditfield(app.GridLayout6, 'text');
            app.HeightofBeam.Editable = 'off';
            app.CounterweightkgLabel = uilabel(app.GridLayout6);
            app.CounterweightkgLabel.Text = 'Counterweight (kg) ';
            app.CounterweightMass = uieditfield(app.GridLayout6, 'text');
            app.CounterweightMass.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOptimisationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end